classdef EditPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        DoneButton  matlab.ui.control.Button
        UITable     matlab.ui.control.Table
    end

    
    properties (Access = private)
        mainapp % Description
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainapp = mainApp;
            if size(mainApp.object_list, 1)>0
                matrix = mainApp.object_list(:,1:5);
                numRows = size(matrix, 1);
                checkboxes = false(numRows, 1); 
                data = [num2cell(matrix), num2cell(checkboxes)]; 
                app.UITable.Data = data;
                app.UITable.ColumnEditable = [false(1, size(matrix, 2)), true];
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            data = app.UITable.Data;
            checkboxes = cell2mat(data(:, end)); 
            selectedRows = find(checkboxes);
            disp('Selected rows are:');
            disp(selectedRows);
            update_obj_list_edit(app.mainapp,selectedRows)
            delete(app.UIFigure);  
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Y_location'; 'X_location'; 'Length'; 'thickness'; 'rotation'; 'Delete'};
            app.UITable.RowName = {};
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditPopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end