classdef Rxpopup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NumofElementsEditField       matlab.ui.control.NumericEditField
        NumofElementsEditFieldLabel  matlab.ui.control.Label
        RXisconsideredtobefullydigitalasdefultLabel  matlab.ui.control.Label
        FilePathEditField            matlab.ui.control.EditField
        FilePathEditFieldLabel       matlab.ui.control.Label
        RXLengthmEditField           matlab.ui.control.NumericEditField
        RXLengthmEditFieldLabel      matlab.ui.control.Label
        RXRotation9090degEditField   matlab.ui.control.NumericEditField
        RXRotation9090degEditFieldLabel  matlab.ui.control.Label
        RXLocationYmEditField        matlab.ui.control.NumericEditField
        RXLocationYmEditFieldLabel   matlab.ui.control.Label
        RXLocationXmEditField        matlab.ui.control.NumericEditField
        RXLocationXmEditFieldLabel   matlab.ui.control.Label
        BrowseButton                 matlab.ui.control.Button
        ApplyButton                  matlab.ui.control.Button
        AnalougeRXArrayCheckBox      matlab.ui.control.CheckBox
        ReceiverPropertiesLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        MainApp % Description
    end
    
    methods (Access = public)
        
        function properties = getProperties(app)
            properties.Xlocation = app.RXLocationXmEditField.Value;
            properties.Ylocation = app.RXLocationYmEditField.Value;
            properties.rotation = app.RXRotation9090degEditField.Value;
            properties.length = app.RXLengthmEditField.Value;
            properties.elements = app.NumofElementsEditField.Value;
            properties.RX_file = "S";
            properties.analougemode = 0;
            if app.AnalougeRXArrayCheckBox.Value
                properties.analougemode = 1;
                properties.RX_file = string(app.FilePathEditField.Value);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
        end

        % Value changed function: AnalougeRXArrayCheckBox
        function AnalougeRXArrayCheckBoxValueChanged(app, event)
            value = app.AnalougeRXArrayCheckBox.Value;
             if value
                app.FilePathEditField.Visible = 'on';
                app.BrowseButton.Enable = 'on';
            else
                app.FilePathEditFieldLabel.Enable = 'off';
                app.BrowseButton.Visible = 'off';
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            properties = app.getProperties();
            RX = [properties.Ylocation,properties.Xlocation,properties.length,...
                properties.rotation,properties.analougemode,properties.elements];
            RX_files = properties.RX_file;
            Rx_list_update(app.MainApp,RX,RX_files);
            delete(app.UIFigure);  
            delete(app);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile('*.txt*'); 
            if isequal(file, 0)
                disp('User canceled the selection');
            else
                fullFilePath = fullfile(path, file);
                app.FilePathEditField.Value = fullFilePath;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReceiverPropertiesLabel = uilabel(app.UIFigure);
            app.ReceiverPropertiesLabel.Text = 'Receiver Properties';
            app.AnalougeRXArrayCheckBox = uicheckbox(app.UIFigure);
            app.AnalougeRXArrayCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnalougeRXArrayCheckBoxValueChanged, true);
            app.AnalougeRXArrayCheckBox.Text = 'Analouge RX Array';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Enable = 'off';
            app.BrowseButton.Text = 'Browse';
            app.RXLocationXmEditFieldLabel = uilabel(app.UIFigure);
            app.RXLocationXmEditFieldLabel.Text = 'RX Location X (m)';
            app.RXLocationXmEditField = uieditfield(app.UIFigure, 'numeric');
            app.RXLocationYmEditFieldLabel = uilabel(app.UIFigure);
            app.RXLocationYmEditFieldLabel.Text = 'RX Location  Y (m)';
            app.RXLocationYmEditField = uieditfield(app.UIFigure, 'numeric');
            app.RXRotation9090degEditFieldLabel = uilabel(app.UIFigure);
            app.RXRotation9090degEditFieldLabel.Text = 'RX Rotation (-90,+90) (deg)';
            app.RXRotation9090degEditField = uieditfield(app.UIFigure, 'numeric');
            app.RXLengthmEditFieldLabel = uilabel(app.UIFigure);
            app.RXLengthmEditFieldLabel.Text = 'RX Length (m)';
            app.RXLengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Enable = 'off';
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.Enable = 'off';
            app.RXisconsideredtobefullydigitalasdefultLabel = uilabel(app.UIFigure);
            app.RXisconsideredtobefullydigitalasdefultLabel.Text = 'RX is considered to be fully digital as defult';
            app.NumofElementsEditFieldLabel = uilabel(app.UIFigure);
            app.NumofElementsEditFieldLabel.Text = 'Num. of Elements';
            app.NumofElementsEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rxpopup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end