classdef PropertyPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BrowseButton                    matlab.ui.control.Button
        FilePathEditField               matlab.ui.control.EditField
        FilePathEditFieldLabel          matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        ReconfigurableInteligentSurfaceModeCheckBox  matlab.ui.control.CheckBox
        LcmmEditField                   matlab.ui.control.NumericEditField
        LcmmEditFieldLabel              matlab.ui.control.Label
        HrmsmmEditField                 matlab.ui.control.NumericEditField
        HrmsmmEditFieldLabel            matlab.ui.control.Label
        RoughSurfaceCheckBox            matlab.ui.control.CheckBox
        PowerReflectionRatio01EditField  matlab.ui.control.NumericEditField
        PowerReflectionRatio01EditFieldLabel  matlab.ui.control.Label
        ObjectTicknessmEditField        matlab.ui.control.NumericEditField
        ObjectTicknessmEditFieldLabel   matlab.ui.control.Label
        ObjectLengthmEditField          matlab.ui.control.NumericEditField
        ObjectLengthmEditFieldLabel     matlab.ui.control.Label
        ObjectRotation9090degEditField  matlab.ui.control.NumericEditField
        ObjectRotation9090degLabel      matlab.ui.control.Label
        ObjectLocationYmEditField       matlab.ui.control.NumericEditField
        ObjectLocationYmEditFieldLabel  matlab.ui.control.Label
        ObjectLocationXmEditField       matlab.ui.control.NumericEditField
        ObjectLocationXmEditFieldLabel  matlab.ui.control.Label
        ReflectorBlockagePropertiesLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp
    end
    
    methods (Access = public)
        
        function properties  = getProperties(app)
            properties.Xlocation = app.ObjectLocationXmEditField.Value;
            properties.Ylocation = app.ObjectLocationYmEditField.Value;
            properties.rotation = app.ObjectRotation9090degEditField.Value;
            properties.length = app.ObjectLengthmEditField.Value;
            properties.thickness = app.ObjectTicknessmEditField.Value;
            properties.powerratio = app.PowerReflectionRatio01EditField.Value;
            properties.hrms = 0;
            properties.lc = 10;
            properties.RIS_file = "S";
            properties.RIS_mode = 0;
            if app.RoughSurfaceCheckBox.Value
                properties.hrms = app.HrmsmmEditField.Value;
                properties.lc = app.LcmmEditField.Value;
            end
            if app.ReconfigurableInteligentSurfaceModeCheckBox.Value
                properties.RIS_mode = 1;
                properties.RIS_file = string(app.FilePathEditField.Value);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
        end

        % Value changed function: RoughSurfaceCheckBox
        function RoughSurfaceCheckBoxValueChanged(app, event)
            value = app.RoughSurfaceCheckBox.Value;
                        
            if value
                app.LcmmEditField.Enable = 'on';
                app.HrmsmmEditField.Enable = 'on';
            else
                app.LcmmEditField.Enable = 'off';
                app.HrmsmmEditField.Enable = 'off';
            end
        end

        % Value changed function: 
        % ReconfigurableInteligentSurfaceModeCheckBox
        function ReconfigurableInteligentSurfaceModeCheckBoxValueChanged(app, event)
            value = app.ReconfigurableInteligentSurfaceModeCheckBox.Value;
            if value
                app.FilePathEditFieldLabel.Enable = 'on';
                app.BrowseButton.Enable = 'on';
            else
                app.FilePathEditFieldLabel.Enable = 'off';
                app.BrowseButton.Enable = 'off';
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            properties = app.getProperties();
            object = [properties.Ylocation,properties.Xlocation,properties.length,...
                properties.thickness,properties.rotation,properties.powerratio,properties.rotation,...
                properties.hrms,properties.lc,properties.RIS_mode];
            RIS_file = properties.RIS_file;
            update_obj_list(app.MainApp,object,RIS_file);
            delete(app.UIFigure);  
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile('*.txt*'); 
            if isequal(file, 0)
                disp('User canceled the selection');
            else
                fullFilePath = fullfile(path, file);
                app.FilePathEditField.Value = fullFilePath;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ReflectorBlockagePropertiesLabel = uilabel(app.UIFigure);
            app.ReflectorBlockagePropertiesLabel.Text = 'Reflector/Blockage Properties';
            app.ObjectLocationXmEditFieldLabel = uilabel(app.UIFigure);
            app.ObjectLocationXmEditFieldLabel.Text = 'Object Location X (m)';
            app.ObjectLocationXmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ObjectLocationYmEditFieldLabel = uilabel(app.UIFigure);
            app.ObjectLocationYmEditFieldLabel.Text = 'Object Location Y (m)';
            app.ObjectLocationYmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ObjectRotation9090degLabel = uilabel(app.UIFigure);
            app.ObjectRotation9090degLabel.Text = 'Object Rotation (-90,+90) (deg)';
            app.ObjectRotation9090degEditField = uieditfield(app.UIFigure, 'numeric');
            app.ObjectLengthmEditFieldLabel = uilabel(app.UIFigure);
            app.ObjectLengthmEditFieldLabel.Text = 'Object Length (m)';
            app.ObjectLengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ObjectTicknessmEditFieldLabel = uilabel(app.UIFigure);
            app.ObjectTicknessmEditFieldLabel.Text = 'Object Tickness (m)';
            app.ObjectTicknessmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerReflectionRatio01EditFieldLabel = uilabel(app.UIFigure);
            app.PowerReflectionRatio01EditFieldLabel.Text = 'Power Reflection Ratio (0-1)';
            app.PowerReflectionRatio01EditField = uieditfield(app.UIFigure, 'numeric');
            app.RoughSurfaceCheckBox = uicheckbox(app.UIFigure);
            app.RoughSurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @RoughSurfaceCheckBoxValueChanged, true);
            app.RoughSurfaceCheckBox.Text = 'Rough Surface';
            app.HrmsmmEditFieldLabel = uilabel(app.UIFigure);
            app.HrmsmmEditFieldLabel.Enable = 'off';
            app.HrmsmmEditFieldLabel.Text = 'Hrms (mm)';
            app.HrmsmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HrmsmmEditField.Enable = 'off';
            app.LcmmEditFieldLabel = uilabel(app.UIFigure);
            app.LcmmEditFieldLabel.Enable = 'off';
            app.LcmmEditFieldLabel.Text = 'Lc (mm)';
            app.LcmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LcmmEditField.Enable = 'off';
            app.ReconfigurableInteligentSurfaceModeCheckBox = uicheckbox(app.UIFigure);
            app.ReconfigurableInteligentSurfaceModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReconfigurableInteligentSurfaceModeCheckBoxValueChanged, true);
            app.ReconfigurableInteligentSurfaceModeCheckBox.Text = 'Reconfigurable Inteligent Surface Mode';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Enable = 'off';
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.Enable = 'off';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Enable = 'off';
            app.BrowseButton.Text = 'Browse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PropertyPopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end