classdef Txpopup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ApplyButton                     matlab.ui.control.Button
        ApertureDimensionmmEditField    matlab.ui.control.NumericEditField
        ApertureDimensionmmEditFieldLabel  matlab.ui.control.Label
        BesseBeamAngledegEditField      matlab.ui.control.NumericEditField
        BesseBeamAngledegEditFieldLabel  matlab.ui.control.Label
        CurvatureParameterEditField     matlab.ui.control.NumericEditField
        CurvatureParameterEditFieldLabel  matlab.ui.control.Label
        FocusPointmEditField            matlab.ui.control.NumericEditField
        FocusPointmEditFieldLabel       matlab.ui.control.Label
        SteeringangledegEditField       matlab.ui.control.NumericEditField
        SteeringangledegEditFieldLabel  matlab.ui.control.Label
        WaveFrontDropDown               matlab.ui.control.DropDown
        WaveFrontDropDownLabel          matlab.ui.control.Label
        TXLocationYmEditField           matlab.ui.control.NumericEditField
        TXLocationYmEditFieldLabel      matlab.ui.control.Label
        TransmitterPropertiesLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        MainApp % Description
    end
    
    methods (Access = public)
        
        function properties = get_properties(app)
            properties.Txlocation = app.TXLocationYmEditField.Value;
            properties.apperture = app.ApertureDimensionmmEditField.Value;
            properties.steering = app.SteeringangledegEditField.Value;
            properties.focus = app.FocusPointmEditField.Value;
            properties.curve = app.CurvatureParameterEditField.Value;
            properties.bessel = app.BesseBeamAngledegEditField.Value;
            if app.WaveFrontDropDown.Value == "Beam Steering"
                properties.beamtype = 'Gaussian';
            elseif app.WaveFrontDropDown.Value == "Beam Focusing"
                properties.beamtype = 'Gaussian_BFocusing';
            elseif app.WaveFrontDropDown.Value == "Airy Beam"
                properties.beamtype = 'Airy';
            else
                properties.beamtype = 'Bessel';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            properties = app.get_properties();
            Tx = [properties.Txlocation ,properties.apperture,properties.steering,properties.focus,properties.curve,properties.bessel];
            beam_type = properties.beamtype;
            Tx_list_update(app.MainApp,Tx,string(beam_type));
            delete(app.UIFigure);  
            delete(app);
        end

        % Value changed function: WaveFrontDropDown
        function WaveFrontDropDownValueChanged(app, event)
            value = app.WaveFrontDropDown.Value;
            if app.WaveFrontDropDown.Value == "Beam Steering"
                app.SteeringangledegEditField.Enable = 'on';
                app.FocusPointmEditField.Enable = 'off';
                app.CurvatureParameterEditField.Enable = 'off';
                app.BesseBeamAngledegEditField.Enable = 'off';
            elseif app.WaveFrontDropDown.Value == "Beam Focusing"
                app.SteeringangledegEditField.Enable = 'on';
                app.FocusPointmEditField.Enable = 'on';
                app.CurvatureParameterEditField.Enable = 'off';
                app.BesseBeamAngledegEditField.Enable = 'off';
            elseif app.WaveFrontDropDown.Value == "Airy Beam"
                app.SteeringangledegEditField.Enable = 'on';
                app.FocusPointmEditField.Enable = 'on';
                app.CurvatureParameterEditField.Enable = 'on';
                app.BesseBeamAngledegEditField.Enable = 'off';
            else
                app.SteeringangledegEditField.Enable = 'off';
                app.FocusPointmEditField.Enable = 'off';
                app.CurvatureParameterEditField.Enable = 'off';
                app.BesseBeamAngledegEditField.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TransmitterPropertiesLabel = uilabel(app.UIFigure);
            app.TransmitterPropertiesLabel.Text = 'Transmitter Properties';
            app.TXLocationYmEditFieldLabel = uilabel(app.UIFigure);
            app.TXLocationYmEditFieldLabel.Text = 'TX Location Y (m)';
            app.TXLocationYmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaveFrontDropDownLabel = uilabel(app.UIFigure);
            app.WaveFrontDropDownLabel.Text = 'Wave Front';
            app.WaveFrontDropDown = uidropdown(app.UIFigure);
            app.WaveFrontDropDown.Items = {'Beam Steering', 'Beam Focusing', 'Airy Beam', 'Bessel Beam'};
            app.WaveFrontDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveFrontDropDownValueChanged, true);
            app.WaveFrontDropDown.Value = 'Beam Steering';
            app.SteeringangledegEditFieldLabel = uilabel(app.UIFigure);
            app.SteeringangledegEditFieldLabel.Text = 'Steering angle (deg) ';
            app.SteeringangledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.FocusPointmEditFieldLabel = uilabel(app.UIFigure);
            app.FocusPointmEditFieldLabel.Text = 'Focus Point (m)';
            app.FocusPointmEditField = uieditfield(app.UIFigure, 'numeric');
            app.FocusPointmEditField.Enable = 'off';
            app.CurvatureParameterEditFieldLabel = uilabel(app.UIFigure);
            app.CurvatureParameterEditFieldLabel.Text = 'Curvature Parameter ';
            app.CurvatureParameterEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurvatureParameterEditField.Enable = 'off';
            app.BesseBeamAngledegEditFieldLabel = uilabel(app.UIFigure);
            app.BesseBeamAngledegEditFieldLabel.Text = 'Besse Beam Angle (deg)';
            app.BesseBeamAngledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.BesseBeamAngledegEditField.Enable = 'off';
            app.ApertureDimensionmmEditFieldLabel = uilabel(app.UIFigure);
            app.ApertureDimensionmmEditFieldLabel.Text = 'Aperture Dimension (mm)';
            app.ApertureDimensionmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ApertureDimensionmmEditField.Value = 60;
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Txpopup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end