classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DropDown                        matlab.ui.control.DropDown
        SimulationResultsPanel          matlab.ui.container.Panel
        CalculatedPower                 matlab.ui.control.TextArea
        CalculatedPoweratRXLocationsLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        RXConfigurationPanel            matlab.ui.container.Panel
        ClearAllButton_2                matlab.ui.control.Button
        AddedRXInfoTextArea             matlab.ui.control.TextArea
        AddedRXInfoTextAreaLabel        matlab.ui.control.Label
        AddNewRXButton                  matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        TXConfigurationPanel            matlab.ui.container.Panel
        WavefrontInfoButton             matlab.ui.control.Button
        ClearAllButton_3                matlab.ui.control.Button
        FilePathEditField               matlab.ui.control.EditField
        FilePathEditFieldLabel          matlab.ui.control.Label
        AddedTXInfoTextArea             matlab.ui.control.TextArea
        AddedTXInfoTextAreaLabel        matlab.ui.control.Label
        ApertureDimensionmmEditField    matlab.ui.control.NumericEditField
        ApertureDimensionmmEditFieldLabel  matlab.ui.control.Label
        BesselBeamNonDiffractionAngledegEditField  matlab.ui.control.NumericEditField
        BesselBeamNonDiffractionAngledegEditFieldLabel  matlab.ui.control.Label
        CurvatureParameterEditField     matlab.ui.control.NumericEditField
        CurvatureParameterEditFieldLabel  matlab.ui.control.Label
        FocusPointmEditField            matlab.ui.control.NumericEditField
        FocusPointmEditFieldLabel       matlab.ui.control.Label
        SteeringangledegEditField       matlab.ui.control.NumericEditField
        SteeringangledegEditFieldLabel  matlab.ui.control.Label
        WaveFrontDropDown               matlab.ui.control.DropDown
        WaveFrontDropDownLabel          matlab.ui.control.Label
        FrequencyGHzEditField           matlab.ui.control.NumericEditField
        FrequencyGHzEditFieldLabel      matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        TxParametersLabel               matlab.ui.control.Label
        AddNewTxArrayButton             matlab.ui.control.Button
        MultipleTxArraysCheckBox        matlab.ui.control.CheckBox
        manualTxEfieldconfigurationCheckBox  matlab.ui.control.CheckBox
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        EnvironmentPropertiesPanel      matlab.ui.container.Panel
        EditObjectPropertiesButton      matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        AutoResolutionCheckBox          matlab.ui.control.CheckBox
        ResolutionnumpointsEditField    matlab.ui.control.NumericEditField
        ResolutionnumpointsEditFieldLabel  matlab.ui.control.Label
        DimensionmEditField             matlab.ui.control.NumericEditField
        DimensionmEditFieldLabel        matlab.ui.control.Label
        AddNewReflectorBlockerButton    matlab.ui.control.Button
        AddedObjectsInfoTextArea        matlab.ui.control.TextArea
        AddedObjectsInfoTextAreaLabel   matlab.ui.control.Label
        OutputFileNameEditField         matlab.ui.control.EditField
        OutputFileNameEditFieldLabel    matlab.ui.control.Label
        CalculatingtheNearFieldPropagartionPleaseWaitLabel  matlab.ui.control.Label
        PowerMapButtonGroup             matlab.ui.container.ButtonGroup
        magButton                       matlab.ui.control.RadioButton
        dbButton                        matlab.ui.control.RadioButton
        RunButton                       matlab.ui.control.Button
    end

    
    properties (Access = public)
        object_list = [];
        Rx_list = [];
        RX_files = [];
        RIS_files = [];
        ObjHandles = {};
        RxHandles = {};
        multi_tx_list = [];
        multi_tx_beam_type = [];
        recieved_powers = [];
        Dimension = 1;
        z_length_p = 6000;
        freq = 100;
        B_d = 0; 
        focal = 0;
        steering_angle = 0;
        bessel_angle = 0;
        aperture_size = 60;
        Beamtype = 'Gaussian';
        E_calculated = false;
        E_matrix_f
    end

    
    methods (Access = public)
        
        function update_obj_list(app,obj,RIS_file)
            app.object_list = [app.object_list;obj];
            app.RIS_files = [app.RIS_files;RIS_file];
            app.AddedObjectsInfoTextArea.Visible = 'on'; 
            app.AddedObjectsInfoTextArea.Enable = 'on'; 
            app.AddedObjectsInfoTextAreaLabel.Enable = 'on';
            numObjects = size(app.object_list, 1);
            objectDescriptions = cell(numObjects, 1);
            for i = 1:numObjects
                Y_location = app.object_list(i, 1);
                X_location = app.object_list(i, 2);
                length = app.object_list(i, 3);
                rotation = app.object_list(i, 5);
                objectDescriptions{i} = sprintf('Object %d: Y=%.2f, X=%.2f, Length=%.2f, Rotation=%d degrees', ...
                                                i, Y_location, X_location, length, rotation);
                centerX = X_location;      
                centerY = Y_location;          
                width = app.object_list(i, 4);            
                height = app.object_list(i, 3);           
                rotationAngle = -app.object_list(i, 5)+90;    
                posX = centerX;
                posY = centerY - height/2;
                hold(app.UIAxes, 'on');
                h = rectangle(app.UIAxes, 'Position', [posX, posY, width, height], ...
                              'EdgeColor', 'r', 'LineWidth', 2);
                tform = hgtransform('Parent', app.UIAxes);
                set(h, 'Parent', tform);
                R = makehgtform('translate', [centerX, centerY, 0], 'zrotate', deg2rad(rotationAngle), 'translate', [-centerX, -centerY, 0]);
                set(tform, 'Matrix', R);
                app.ObjHandles{end+1} = h;
                hold(app.UIAxes, 'off');
             end
                textOutput = strjoin(objectDescriptions, newline);
                app.AddedObjectsInfoTextArea.Value = textOutput;
        end
        
        function Rx_list_update(app,Rx_info,RX_file)
            app.Rx_list = [app.Rx_list;Rx_info];
            app.RX_files = [app.RX_files;RX_file];
            numRx = size(app.Rx_list, 1);
            RxDescriptions = cell(numRx, 1);
            for i = 1:numRx
                Y_location = app.Rx_list(i, 1);
                X_location = app.Rx_list(i, 2);
                length = app.Rx_list(i, 3);
                rotation = app.Rx_list(i, 4);
                elements = app.Rx_list(i, 6);
                RxDescriptions{i} = sprintf('RX %d: Y=%.2f, X=%.2f, Length=%.2f, Rotation=%d deg, Num. Elements=%d', ...
                                                i, Y_location, X_location, length, rotation,elements);

                centerX = X_location;      
                centerY = Y_location;     
                height = app.Rx_list(i, 3);        
                width = height * 0.1;
                rotationAngle = -app.Rx_list(i, 4)+90;    
                posX = centerX;
                posY = centerY - height/2;
                hold(app.UIAxes, 'on');
                h = rectangle(app.UIAxes, 'Position', [posX, posY, width, height], ...
                              'EdgeColor', 'g', 'LineWidth', 2);
                tform = hgtransform('Parent', app.UIAxes);
                set(h, 'Parent', tform);
                R = makehgtform('translate', [centerX, centerY, 0], 'zrotate', deg2rad(rotationAngle), 'translate', [-centerX, -centerY, 0]);
                set(tform, 'Matrix', R);
                app.RxHandles{end+1} = h;
                hold(app.UIAxes, 'off');
            end
            
            textOutput = strjoin(RxDescriptions, newline);
            app.AddedRXInfoTextArea.Value = textOutput;
        end
        
        function received_power_update(app)
            numRx = size(app.Rx_list, 1);
            RxDescriptions = cell(numRx, 1);
            for i = 1:numRx
                received_power_x = app.recieved_powers(i);
                RxDescriptions{i} = sprintf('RX %d: recieved power = %.2f (dB)', ...
                                                i, received_power_x);
            end
            
            textOutput = strjoin(RxDescriptions, newline);
            app.CalculatedPower.Value = textOutput;
        end
        
        function Tx_list_update(app,Tx,Tx_beam)
            app.multi_tx_list = [app.multi_tx_list;Tx];
            app.multi_tx_beam_type = [app.multi_tx_beam_type;Tx_beam];
            numTx = size(app.multi_tx_list, 1);
            TxDescriptions = cell(numTx, 1);
            for i = 1:numTx
                Y_location = app.multi_tx_list(i, 1);
                dimention = app.multi_tx_list(i, 2);
                beam_type = app.multi_tx_beam_type(i);
                TxDescriptions{i} = sprintf('TX %d: Y_Loc=%.2f, Aperture(mm)=%d, beam_type=%s', ...
                                                i, Y_location, dimention, beam_type);
            end
            
            textOutput = strjoin(TxDescriptions, newline);
            app.AddedTXInfoTextArea.Value = textOutput;
        end
        
        function draw_objects(app)
            delete_obj_fig(app);
            numObjects = size(app.object_list, 1);
            for i = 1:numObjects
                Y_location = app.object_list(i, 1);
                X_location = app.object_list(i, 2);
                centerX = X_location;      
                centerY = Y_location;          
                width = app.object_list(i, 4);            
                height = app.object_list(i, 3);           
                rotationAngle = -app.object_list(i, 5)+90;    
                posX = centerX;
                posY = centerY - height/2;
                hold(app.UIAxes, 'on');
                h = rectangle(app.UIAxes, 'Position', [posX, posY, width, height], ...
                              'EdgeColor', 'r', 'LineWidth', 2);
                tform = hgtransform('Parent', app.UIAxes);
                set(h, 'Parent', tform);
                R = makehgtform('translate', [centerX, centerY, 0], 'zrotate', deg2rad(rotationAngle), 'translate', [-centerX, -centerY, 0]);
                set(tform, 'Matrix', R);
                app.ObjHandles{end+1} = h;
                hold(app.UIAxes, 'off');
             end
        end
        
        function draw_RX(app)
            delete_Rx_fig(app);
            numRx = size(app.Rx_list, 1);
            for i = 1:numRx
                Y_location = app.Rx_list(i, 1);
                X_location = app.Rx_list(i, 2);
                centerX = X_location;      
                centerY = Y_location;     
                height = app.Rx_list(i, 3);        
                width = height * 0.1;
                rotationAngle = -app.Rx_list(i, 4)+90;    
                posX = centerX;
                posY = centerY - height/2;
                hold(app.UIAxes, 'on');
                h = rectangle(app.UIAxes, 'Position', [posX, posY, width, height], ...
                              'EdgeColor', 'g', 'LineWidth', 2);
                tform = hgtransform('Parent', app.UIAxes);
                set(h, 'Parent', tform);
                R = makehgtform('translate', [centerX, centerY, 0], 'zrotate', deg2rad(rotationAngle), 'translate', [-centerX, -centerY, 0]);
                set(tform, 'Matrix', R);
                app.RxHandles{end+1} = h;
                hold(app.UIAxes, 'off');
            end
        end
        
        function delete_obj_fig(app)
            for i = length(app.ObjHandles):-1:1
                if isvalid(app.ObjHandles{i}) % Check if the object is valid
                    delete(app.ObjHandles{i});
                end
            end
            app.ObjHandles = {};
        end
        
        function delete_Rx_fig(app)
            for i = length(app.RxHandles):-1:1
                if isvalid(app.RxHandles{i}) % Check if the object is valid
                    delete(app.RxHandles{i});
                end
            end
            app.RxHandles = {};
        end
        
        function update_obj_list_edit(app,selectedRows)
            app.object_list(selectedRows, :) = [];
            app.RIS_files(selectedRows, :) = [];
            numObjects = size(app.object_list, 1);
            objectDescriptions = cell(numObjects, 1);
            for i = 1:numObjects
                Y_location = app.object_list(i, 1);
                X_location = app.object_list(i, 2);
                length = app.object_list(i, 3);
                rotation = app.object_list(i, 5);
                objectDescriptions{i} = sprintf('Object %d: Y=%.2f, X=%.2f, Length=%.2f, Rotation=%d degrees', ...
                                                i, Y_location, X_location, length, rotation);
             end
                textOutput = strjoin(objectDescriptions, newline);
                app.AddedObjectsInfoTextArea.Value = textOutput;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            newDimension = 1;
            app.UIAxes.XTick = 0:newDimension/10:newDimension;
            app.UIAxes.YTick = -newDimension/2:newDimension/10:newDimension/2;
            app.UIAxes.XLim = [0 newDimension];
            app.UIAxes.YLim = [-newDimension/2 newDimension/2];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XTickLabel = arrayfun(@num2str, app.UIAxes.XTick, 'UniformOutput', false);
            app.UIAxes.YTickLabel = arrayfun(@num2str, app.UIAxes.YTick, 'UniformOutput', false);

        end

        % Button pushed function: AddNewReflectorBlockerButton
        function AddNewReflectorBlockerButtonPushed(app, event)
            propertyPopup = PropertyPopUp(app);
            waitfor(propertyPopup.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.CalculatingtheNearFieldPropagartionPleaseWaitLabel.Visible = 'on';
            app.RunButton.Enable = 'off';
            drawnow;
            app.Dimension = app.DimensionmEditField.Value;
            min_res = 4*app.Dimension/(0.3/app.freq);
            value = app.AutoResolutionCheckBox.Value;
            if value
                app.z_length_p = round(2*min_res);
                disp(app.z_length_p);
            else
                app.z_length_p = app.ResolutionnumpointsEditField.Value;
            end
            
            app.freq = app.FrequencyGHzEditField.Value;
            app.B_d = app.CurvatureParameterEditField.Value; 
            app.focal = app.FocusPointmEditField.Value;
            app.steering_angle = app.SteeringangledegEditField.Value;
            app.bessel_angle = app.BesselBeamNonDiffractionAngledegEditField.Value;
            app.aperture_size = app.ApertureDimensionmmEditField.Value;
            if app.WaveFrontDropDown.Value == "Beam Steering"
                app.Beamtype = 'Gaussian';
            elseif app.WaveFrontDropDown.Value == "Beam Focusing"
                app.Beamtype = 'Gaussian_BFocusing';
            elseif app.WaveFrontDropDown.Value == "Airy Beam"
                app.Beamtype = 'Airy';
            else
                app.Beamtype = 'Bessel';
            end
            
            if app.z_length_p<min_res
                uialert(app.UIFigure, "Num. points should be higher than "+num2str(round(min_res)), 'Warning', 'Icon', 'warning');
            else
            
            if app.manualTxEfieldconfigurationCheckBox.Value 
                E_field_file = app.FilePathEditField.Value;
                E_pf = readmatrix(E_field_file);
                if app.z_length_p~=length(E_pf)
                uialert(app.UIFigure, "Tx E_field num. points should be equal to the defined resolution", 'Warning', 'Icon', 'warning');
                else
                E_matrix = near_field_propagation(app.freq,app.Dimension,app.z_length_p,app.bessel_angle,app.B_d,app.focal,app.steering_angle,app.aperture_size,app.Beamtype,app.object_list,app.RIS_files,E_field_file,app.multi_tx_list,app.multi_tx_beam_type,1);
                E_matrix = E_matrix./max(abs(E_matrix(:)));
                app.E_matrix_f = E_matrix;
                [Y,Z] = grid_info(app.Dimension,app.z_length_p,app.freq);
                if app.PowerMapButtonGroup.SelectedObject == app.dbButton
                    imagesc(app.UIAxes,Z,Y,mag2db(abs(E_matrix)));
                    title(app.UIAxes,'Coverage Map')
                    colormap(app.UIAxes,'parula');
                    colorbar(app.UIAxes);
                    xlabel(app.UIAxes, 'X (m)');
                    ylabel(app.UIAxes, 'Y (m)');
                    xlim(app.UIAxes, [min(Z) max(Z)]);
                    ylim(app.UIAxes, [min(Y) max(Y)]);
                    clim(app.UIAxes,[-90 0]);
                else
                    imagesc(app.UIAxes,Z,Y,(abs(E_matrix)));
                    title(app.UIAxes,'Coverage Map')
                    colormap(app.UIAxes,'hot');
                    colorbar(app.UIAxes);
                    xlabel(app.UIAxes, 'X (m)');
                    ylabel(app.UIAxes, 'Y (m)');
                    xlim(app.UIAxes, [0 app.Dimension]);
                    ylim(app.UIAxes, [-app.Dimension/2 app.Dimension/2]);
                    clim(app.UIAxes,[0 1]);
                end
                app.E_calculated = true;
                app.recieved_powers = RX_power(E_matrix,app.Rx_list,app.RX_files,Y,Z);
                received_power_update(app);
                end

            elseif app.MultipleTxArraysCheckBox.Value
                E_field_file = "S";
                E_matrix = near_field_propagation(app.freq,app.Dimension,app.z_length_p,app.bessel_angle,app.B_d,app.focal,app.steering_angle,app.aperture_size,app.Beamtype,app.object_list,app.RIS_files,E_field_file,app.multi_tx_list,app.multi_tx_beam_type,2);
                E_matrix = E_matrix./max(abs(E_matrix(:)));
                app.E_matrix_f = E_matrix;
                [Y,Z] = grid_info(app.Dimension,app.z_length_p,app.freq);
                if app.PowerMapButtonGroup.SelectedObject == app.dbButton
                    imagesc(app.UIAxes,Z,Y,mag2db(abs(E_matrix)));
                    title(app.UIAxes,'Coverage Map')
                    colormap(app.UIAxes,'parula');
                    colorbar(app.UIAxes);
                    xlabel(app.UIAxes, 'X (m)');
                    ylabel(app.UIAxes, 'Y (m)');
                    xlim(app.UIAxes, [min(Z) max(Z)]);
                    ylim(app.UIAxes, [min(Y) max(Y)]);
                    clim(app.UIAxes,[-90 0]);
                else
                    imagesc(app.UIAxes,Z,Y,(abs(E_matrix)));
                    title(app.UIAxes,'Coverage Map')
                    colormap(app.UIAxes,'hot');
                    colorbar(app.UIAxes);
                    xlabel(app.UIAxes, 'X (m)');
                    ylabel(app.UIAxes, 'Y (m)');
                    xlim(app.UIAxes, [0 app.Dimension]);
                    ylim(app.UIAxes, [-app.Dimension/2 app.Dimension/2]);
                    clim(app.UIAxes,[0 1]);
                end
                app.E_calculated = true;
                app.recieved_powers = RX_power(E_matrix,app.Rx_list,app.RX_files,Y,Z);
                received_power_update(app);
                
                
            else
                E_field_file = "S";
                E_matrix = near_field_propagation(app.freq,app.Dimension,app.z_length_p,app.bessel_angle,app.B_d,app.focal,app.steering_angle,app.aperture_size,app.Beamtype,app.object_list,app.RIS_files,E_field_file,app.multi_tx_list,app.multi_tx_beam_type,0);
                E_matrix = E_matrix./max(abs(E_matrix(:)));
                app.E_matrix_f = E_matrix;
                [Y,Z] = grid_info(app.Dimension,app.z_length_p,app.freq);
                if app.PowerMapButtonGroup.SelectedObject == app.dbButton
                    imagesc(app.UIAxes,Z,Y,mag2db(abs(E_matrix)));
                    title(app.UIAxes,'Coverage Map')
                    colormap(app.UIAxes,'parula');
                    colorbar(app.UIAxes);
                    xlabel(app.UIAxes, 'X (m)');
                    ylabel(app.UIAxes, 'Y (m)');
                    xlim(app.UIAxes, [min(Z) max(Z)]);
                    ylim(app.UIAxes, [min(Y) max(Y)]);
                    clim(app.UIAxes,[-90 0]);
                else
                    imagesc(app.UIAxes,Z,Y,(abs(E_matrix)));
                    title(app.UIAxes,'Coverage Map')
                    colormap(app.UIAxes,'hot');
                    colorbar(app.UIAxes);
                    xlabel(app.UIAxes, 'X (m)');
                    ylabel(app.UIAxes, 'Y (m)');
                    xlim(app.UIAxes, [0 app.Dimension]);
                    ylim(app.UIAxes, [-app.Dimension/2 app.Dimension/2]);
                    clim(app.UIAxes,[0 max(abs(E_matrix(:)))]);
                end
                app.E_calculated = true;
                app.recieved_powers = RX_power(E_matrix,app.Rx_list,app.RX_files,Y,Z);
                received_power_update(app);
            end

            if isempty(app.OutputFileNameEditField.Value)
                disp("The output result is not saved");
            else
                if app.DropDown.Value == ".txt"
                    writematrix(E_matrix,app.OutputFileNameEditField.Value+".txt");
                else
                fileID = fopen(app.OutputFileNameEditField.Value+".bin", 'w');
                realPart = real(E_matrix);
                imagPart = imag(E_matrix);
                fwrite(fileID, [realPart(:) imagPart(:)]', 'double');
                fclose(fileID);
                end
            end
            end
            app.CalculatingtheNearFieldPropagartionPleaseWaitLabel.Visible = 'off';
            app.RunButton.Enable = 'on';
            draw_objects(app);
            draw_RX(app);
        end

        % Value changed function: DimensionmEditField
        function DimensionmEditFieldValueChanged(app, event)
            newDimension = app.DimensionmEditField.Value;
            app.UIAxes.XTick = 0:newDimension/10:newDimension;
            app.UIAxes.YTick = -newDimension/2:newDimension/10:newDimension/2;
            app.UIAxes.XLim = [0 newDimension];
            app.UIAxes.YLim = [-newDimension/2 newDimension/2];
            app.UIAxes.XTickLabel = arrayfun(@num2str, app.UIAxes.XTick, 'UniformOutput', false);
            app.UIAxes.YTickLabel = arrayfun(@num2str, app.UIAxes.YTick, 'UniformOutput', false);

        end

        % Value changed function: manualTxEfieldconfigurationCheckBox
        function manualTxEfieldconfigurationCheckBoxValueChanged(app, event)
            value = app.manualTxEfieldconfigurationCheckBox.Value;
            if value
                %app.EfieldfilenameEditFieldLabel.Enable = 'on';
                %app.EfieldfilenameEditField.Enable = 'on';
                app.BrowseButton.Enable = 'on';
                app.AddNewTxArrayButton .Enable= 'off';
                app.ClearAllButton_3.Enable = 'off';
                app.MultipleTxArraysCheckBox.Value = false;
                app.WaveFrontDropDown.Enable = 'off';
                app.ApertureDimensionmmEditField.Enable = 'off';
                app.SteeringangledegEditField.Enable = 'off';
                app.FocusPointmEditField.Enable = 'off';
                app.CurvatureParameterEditField.Enable = 'off';
                app.BesselBeamNonDiffractionAngledegEditField.Enable = 'off';
                app.AddedTXInfoTextArea.Enable = 'off';
                app.AddedTXInfoTextAreaLabel.Enable = 'off';
            else
                app.BrowseButton.Enable = 'off';
                app.ClearAllButton_3.Enable = 'off';
                %app.EfieldfilenameEditField.Enable = 'off';
                %app.EfieldfilenameEditFieldLabel.Enable = 'off';
                app.WaveFrontDropDown.Enable = 'on';
                app.ApertureDimensionmmEditField.Enable = 'on';
                app.SteeringangledegEditField.Enable = 'on';
                app.FocusPointmEditField.Enable = 'off';
                app.CurvatureParameterEditField.Enable = 'off';
                app.BesselBeamNonDiffractionAngledegEditField.Enable = 'off';
                app.WaveFrontDropDown.Value = "Beam Steering";
                app.AddedTXInfoTextArea.Enable = 'off';
                app.AddedTXInfoTextAreaLabel.Enable = 'off';
            end
        end

        % Value changed function: MultipleTxArraysCheckBox
        function MultipleTxArraysCheckBoxValueChanged(app, event)
            value = app.MultipleTxArraysCheckBox.Value;
            if value
                app.BrowseButton.Enable = 'off';
                %app.EfieldfilenameEditFieldLabel.Enable = 'off';
                %app.EfieldfilenameEditField.Enable = 'off';
                app.ClearAllButton_3.Enable = 'on';
                app.AddedTXInfoTextArea.Enable = 'on';
                app.AddedTXInfoTextAreaLabel.Enable = 'on';
                app.AddNewTxArrayButton.Enable= 'on';
                app.manualTxEfieldconfigurationCheckBox.Value = false;
                app.WaveFrontDropDown.Enable = 'off';
                app.ApertureDimensionmmEditField.Enable = 'off';
                app.SteeringangledegEditField.Enable = 'off';
                app.FocusPointmEditField.Enable = 'off';
                app.CurvatureParameterEditField.Enable = 'off';
                app.BesselBeamNonDiffractionAngledegEditField.Enable = 'off';
            else
                app.AddedTXInfoTextArea.Enable = 'off';
                app.AddedTXInfoTextAreaLabel.Enable = 'off';
                app.ClearAllButton_3.Enable = 'off';
                app.BrowseButton.Enable = 'off';
                %app.EfieldfilenameEditField.Enable = 'off';
                %app.EfieldfilenameEditFieldLabel.Enable = 'off';
                app.AddNewTxArrayButton.Enable = 'off';
                app.WaveFrontDropDown.Enable = 'on';
                app.ApertureDimensionmmEditField.Enable = 'on';
                app.SteeringangledegEditField.Enable = 'on';
                app.FocusPointmEditField.Enable = 'off';
                app.CurvatureParameterEditField.Enable = 'off';
                app.BesselBeamNonDiffractionAngledegEditField.Enable = 'off';
                app.WaveFrontDropDown.Value = "Beam Steering";
            end
        end

        % Selection changed function: PowerMapButtonGroup
        function PowerMapButtonGroupSelectionChanged(app, event)
            selectedButton = app.PowerMapButtonGroup.SelectedObject;
            [Y,Z] = grid_info(app.Dimension,app.z_length_p,app.freq);
            if app.PowerMapButtonGroup.SelectedObject == app.dbButton
                imagesc(app.UIAxes,Z,Y,mag2db(abs(app.E_matrix_f)));
                colormap(app.UIAxes,'parula');
                title(app.UIAxes,'Coverage Map')
                colorbar(app.UIAxes);
                xlabel(app.UIAxes, 'X (m)');
                ylabel(app.UIAxes, 'Y (m)');
                xlim(app.UIAxes, [min(Z) max(Z)]);
                ylim(app.UIAxes, [min(Y) max(Y)]);
                clim(app.UIAxes,[-90 0]);
            else
                imagesc(app.UIAxes,Z,Y,(abs(app.E_matrix_f)));
                title(app.UIAxes,'Coverage Map')
                colormap(app.UIAxes,'hot');
                colorbar(app.UIAxes);
                xlabel(app.UIAxes, 'X (m)');
                ylabel(app.UIAxes, 'Y (m)');
                xlim(app.UIAxes, [min(Z) max(Z)]);
                ylim(app.UIAxes, [min(Y) max(Y)]);
                clim(app.UIAxes,[0 1]);
            end
            draw_objects(app);
            draw_RX(app);
        end

        % Value changed function: WaveFrontDropDown
        function WaveFrontDropDownValueChanged(app, event)
            value = app.WaveFrontDropDown.Value;
            if app.WaveFrontDropDown.Value == "Beam Steering"
                app.SteeringangledegEditField.Enable = 'on';
                app.FocusPointmEditField.Enable = 'off';
                app.CurvatureParameterEditField.Enable = 'off';
                app.BesselBeamNonDiffractionAngledegEditField.Enable = 'off';
            elseif app.WaveFrontDropDown.Value == "Beam Focusing"
                app.SteeringangledegEditField.Enable = 'on';
                app.FocusPointmEditField.Enable = 'on';
                app.CurvatureParameterEditField.Enable = 'off';
                app.BesselBeamNonDiffractionAngledegEditField.Enable = 'off';
            elseif app.WaveFrontDropDown.Value == "Airy Beam"
                app.SteeringangledegEditField.Enable = 'on';
                app.FocusPointmEditField.Enable = 'on';
                app.CurvatureParameterEditField.Enable = 'on';
                app.BesselBeamNonDiffractionAngledegEditField.Enable = 'off';
            else
                app.SteeringangledegEditField.Enable = 'off';
                app.FocusPointmEditField.Enable = 'off';
                app.CurvatureParameterEditField.Enable = 'off';
                app.BesselBeamNonDiffractionAngledegEditField.Enable = 'on';
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile('*.txt*'); 
            if isequal(file, 0)
                disp('User canceled the selection');
            else
                fullFilePath = fullfile(path, file);
                app.FilePathEditField.Value = fullFilePath;
            end
        end

        % Button pushed function: AddNewRXButton
        function AddNewRXButtonPushed(app, event)
            Rxpopup_info = Rxpopup(app);
            waitfor(Rxpopup_info.UIFigure);
            disp(app.object_list);
        end

        % Button pushed function: AddNewTxArrayButton
        function AddNewTxArrayButtonPushed(app, event)
            Txpopup_info = Txpopup(app);
            waitfor(Txpopup_info.UIFigure);
            disp(app.object_list);
        end

        % Value changed function: AutoResolutionCheckBox
        function AutoResolutionCheckBoxValueChanged(app, event)
            value = app.AutoResolutionCheckBox.Value;
            if value
                app.ResolutionnumpointsEditField.Enable='off';
            else
                app.ResolutionnumpointsEditField.Enable='on';
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.object_list = [];
            app.RIS_files = [];
            app.AddedObjectsInfoTextArea.Visible = 'on'; 
            app.AddedObjectsInfoTextArea.Enable = 'off'; 
            app.AddedObjectsInfoTextAreaLabel.Enable = 'off';
            app.AddedObjectsInfoTextArea.Value = "";
            delete_obj_fig(app);
            
        end

        % Button pushed function: ClearAllButton_2
        function ClearAllButton_2Pushed(app, event)
            app.Rx_list = [];
            app.RX_files = [];
            app.AddedRXInfoTextArea.Value = "";
            delete_Rx_fig(app);
            app.CalculatedPower.Value = "";
        end

        % Button pushed function: ClearAllButton_3
        function ClearAllButton_3Pushed(app, event)
            app.multi_tx_list = [];
            app.multi_tx_beam_type = [];
            app.AddedTXInfoTextArea.Value = "";
        end

        % Button pushed function: EditObjectPropertiesButton
        function EditObjectPropertiesButtonPushed(app, event)
            editPopup = EditPopUp(app);
            waitfor(editPopup.UIFigure); 
            draw_objects(app);
        end

        % Button pushed function: WavefrontInfoButton
        function WavefrontInfoButtonPushed(app, event)
            img = imread('doc/Wavefront_Exp.png'); 
            figure;
            imshow(img);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PowerMapButtonGroup = uibuttongroup(app.UIFigure);
            app.PowerMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PowerMapButtonGroupSelectionChanged, true);
            app.PowerMapButtonGroup.Title = 'Power Map';
            app.dbButton = uiradiobutton(app.PowerMapButtonGroup);
            app.dbButton.Text = 'db';
            app.dbButton.Value = true;
            app.magButton = uiradiobutton(app.PowerMapButtonGroup);
            app.magButton.Text = 'mag';
            app.CalculatingtheNearFieldPropagartionPleaseWaitLabel = uilabel(app.UIFigure);
            app.CalculatingtheNearFieldPropagartionPleaseWaitLabel.Visible = 'off';
            app.CalculatingtheNearFieldPropagartionPleaseWaitLabel.Text = 'Calculating the Near Field Propagartion, Please Wait!';
            app.OutputFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputFileNameEditFieldLabel.Text = 'Output File Name';
            app.OutputFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.EnvironmentPropertiesPanel = uipanel(app.UIFigure);
            app.EnvironmentPropertiesPanel.Title = 'Environment Properties ';
            app.AddedObjectsInfoTextAreaLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.AddedObjectsInfoTextAreaLabel.Enable = 'off';
            app.AddedObjectsInfoTextAreaLabel.Text = 'Added Objects Info.';
            app.AddedObjectsInfoTextArea = uitextarea(app.EnvironmentPropertiesPanel);
            app.AddedObjectsInfoTextArea.Editable = 'off';
            app.AddedObjectsInfoTextArea.Enable = 'off';
            app.AddNewReflectorBlockerButton = uibutton(app.EnvironmentPropertiesPanel, 'push');
            app.AddNewReflectorBlockerButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewReflectorBlockerButtonPushed, true);
            app.AddNewReflectorBlockerButton.Text = 'Add New Reflector/Blocker';
            app.DimensionmEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.DimensionmEditFieldLabel.Text = 'Dimension (m)';
            app.DimensionmEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.DimensionmEditField.ValueChangedFcn = createCallbackFcn(app, @DimensionmEditFieldValueChanged, true);
            app.DimensionmEditField.Value = 1;
            app.ResolutionnumpointsEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.ResolutionnumpointsEditFieldLabel.Text = 'Resolution num. points';
            app.ResolutionnumpointsEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.ResolutionnumpointsEditField.Value = 2000;
            app.AutoResolutionCheckBox = uicheckbox(app.EnvironmentPropertiesPanel);
            app.AutoResolutionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoResolutionCheckBoxValueChanged, true);
            app.AutoResolutionCheckBox.Text = 'Auto Resolution';
            app.ClearAllButton = uibutton(app.EnvironmentPropertiesPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.EditObjectPropertiesButton = uibutton(app.EnvironmentPropertiesPanel, 'push');
            app.EditObjectPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @EditObjectPropertiesButtonPushed, true);
            app.EditObjectPropertiesButton.Text = 'Edit Object Properties';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TXConfigurationPanel = uipanel(app.UIFigure);
            app.TXConfigurationPanel.Title = 'TX Configuration';
            app.Label_2 = uilabel(app.TXConfigurationPanel);
            app.Label_2.Text = '';
            app.manualTxEfieldconfigurationCheckBox = uicheckbox(app.TXConfigurationPanel);
            app.manualTxEfieldconfigurationCheckBox.ValueChangedFcn = createCallbackFcn(app, @manualTxEfieldconfigurationCheckBoxValueChanged, true);
            app.manualTxEfieldconfigurationCheckBox.Text = 'manual Tx E field configuration';
            app.MultipleTxArraysCheckBox = uicheckbox(app.TXConfigurationPanel);
            app.MultipleTxArraysCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultipleTxArraysCheckBoxValueChanged, true);
            app.MultipleTxArraysCheckBox.Text = 'Multiple Tx Arrays';
            app.AddNewTxArrayButton = uibutton(app.TXConfigurationPanel, 'push');
            app.AddNewTxArrayButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewTxArrayButtonPushed, true);
            app.AddNewTxArrayButton.Enable = 'off';
            app.AddNewTxArrayButton.Text = 'Add New Tx Array';
            app.TxParametersLabel = uilabel(app.TXConfigurationPanel);
            app.TxParametersLabel.Text = 'Tx Parameters';
            app.BrowseButton = uibutton(app.TXConfigurationPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Enable = 'off';
            app.BrowseButton.Text = 'Browse';
            app.FrequencyGHzEditFieldLabel = uilabel(app.TXConfigurationPanel);
            app.FrequencyGHzEditFieldLabel.Text = 'Frequency (GHz)';
            app.FrequencyGHzEditField = uieditfield(app.TXConfigurationPanel, 'numeric');
            app.FrequencyGHzEditField.Value = 100;
            app.WaveFrontDropDownLabel = uilabel(app.TXConfigurationPanel);
            app.WaveFrontDropDownLabel.Text = 'Wave Front';
            app.WaveFrontDropDown = uidropdown(app.TXConfigurationPanel);
            app.WaveFrontDropDown.Items = {'Beam Steering', 'Beam Focusing', 'Airy Beam', 'Bessel Beam'};
            app.WaveFrontDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveFrontDropDownValueChanged, true);
            app.WaveFrontDropDown.Value = 'Beam Steering';
            app.SteeringangledegEditFieldLabel = uilabel(app.TXConfigurationPanel);
            app.SteeringangledegEditFieldLabel.Text = 'Steering angle (deg) ';
            app.SteeringangledegEditField = uieditfield(app.TXConfigurationPanel, 'numeric');
            app.FocusPointmEditFieldLabel = uilabel(app.TXConfigurationPanel);
            app.FocusPointmEditFieldLabel.Text = 'Focus Point (m)';
            app.FocusPointmEditField = uieditfield(app.TXConfigurationPanel, 'numeric');
            app.FocusPointmEditField.Enable = 'off';
            app.CurvatureParameterEditFieldLabel = uilabel(app.TXConfigurationPanel);
            app.CurvatureParameterEditFieldLabel.Text = 'Curvature Parameter ';
            app.CurvatureParameterEditField = uieditfield(app.TXConfigurationPanel, 'numeric');
            app.CurvatureParameterEditField.Enable = 'off';
            app.BesselBeamNonDiffractionAngledegEditFieldLabel = uilabel(app.TXConfigurationPanel);
            app.BesselBeamNonDiffractionAngledegEditFieldLabel.Text = ' Bessel Beam Non-Diffraction Angle (deg)';
            app.BesselBeamNonDiffractionAngledegEditField = uieditfield(app.TXConfigurationPanel, 'numeric');
            app.BesselBeamNonDiffractionAngledegEditField.Enable = 'off';
            app.ApertureDimensionmmEditFieldLabel = uilabel(app.TXConfigurationPanel);
            app.ApertureDimensionmmEditFieldLabel.Text = 'Aperture Dimension (mm)';
            app.ApertureDimensionmmEditField = uieditfield(app.TXConfigurationPanel, 'numeric');
            app.ApertureDimensionmmEditField.Value = 60;
            app.AddedTXInfoTextAreaLabel = uilabel(app.TXConfigurationPanel);
            app.AddedTXInfoTextAreaLabel.Enable = 'off';
            app.AddedTXInfoTextAreaLabel.Text = 'Added TX Info.';
            app.AddedTXInfoTextArea = uitextarea(app.TXConfigurationPanel);
            app.AddedTXInfoTextArea.Editable = 'off';
            app.AddedTXInfoTextArea.Enable = 'off';
            app.FilePathEditFieldLabel = uilabel(app.TXConfigurationPanel);
            app.FilePathEditFieldLabel.Enable = 'off';
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.TXConfigurationPanel, 'text');
            app.FilePathEditField.Enable = 'off';
            app.ClearAllButton_3 = uibutton(app.TXConfigurationPanel, 'push');
            app.ClearAllButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_3Pushed, true);
            app.ClearAllButton_3.Enable = 'off';
            app.ClearAllButton_3.Text = 'Clear All';
            app.WavefrontInfoButton = uibutton(app.TXConfigurationPanel, 'push');
            app.WavefrontInfoButton.ButtonPushedFcn = createCallbackFcn(app, @WavefrontInfoButtonPushed, true);
            app.WavefrontInfoButton.Text = 'Wavefront Info';
            app.RXConfigurationPanel = uipanel(app.UIFigure);
            app.RXConfigurationPanel.Title = 'RX Configuration';
            app.Label_3 = uilabel(app.RXConfigurationPanel);
            app.Label_3.Text = '';
            app.AddNewRXButton = uibutton(app.RXConfigurationPanel, 'push');
            app.AddNewRXButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewRXButtonPushed, true);
            app.AddNewRXButton.Text = 'Add New RX';
            app.AddedRXInfoTextAreaLabel = uilabel(app.RXConfigurationPanel);
            app.AddedRXInfoTextAreaLabel.Text = 'Added RX Info.';
            app.AddedRXInfoTextArea = uitextarea(app.RXConfigurationPanel);
            app.AddedRXInfoTextArea.Editable = 'off';
            app.ClearAllButton_2 = uibutton(app.RXConfigurationPanel, 'push');
            app.ClearAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_2Pushed, true);
            app.ClearAllButton_2.Text = 'Clear All';
            app.SimulationResultsPanel = uipanel(app.UIFigure);
            app.SimulationResultsPanel.Title = 'Simulation Results';
            app.UIAxes = uiaxes(app.SimulationResultsPanel);
            title(app.UIAxes, 'Coverage Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.CalculatedPoweratRXLocationsLabel = uilabel(app.SimulationResultsPanel);
            app.CalculatedPoweratRXLocationsLabel.Text = 'Calculated Power at RX Locations';
            app.CalculatedPower = uitextarea(app.SimulationResultsPanel);
            app.CalculatedPower.Editable = 'off';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'.bin', '.txt'};
            app.DropDown.Value = '.bin';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end